classdef BacktestingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BacktestingAppUIFigure          matlab.ui.Figure
        GenerateCodeButton              matlab.ui.control.Button
        ExportToWorkspaceButton         matlab.ui.control.Button
        ImportSessionButton             matlab.ui.control.Button
        ExportSessionButton             matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        PricesDataTab                   matlab.ui.container.Tab
        NextButton                      matlab.ui.control.Button
        TabGroup3                       matlab.ui.container.TabGroup
        PricesDataTab_2                 matlab.ui.container.Tab
        DataTable                       matlab.ui.control.Table
        SignalDataTab                   matlab.ui.container.Tab
        SignalDataTable                 matlab.ui.control.Table
        SignalDataOptionalButtonGroup   matlab.ui.container.ButtonGroup
        FileImportButton_2              matlab.ui.control.Button
        SignalTTEditField               matlab.ui.control.EditField
        FromFileButton_2                matlab.ui.control.RadioButton
        DropDown_2                      matlab.ui.control.DropDown
        FromWorkspaceButton_2           matlab.ui.control.RadioButton
        PricesDataButtonGroup           matlab.ui.container.ButtonGroup
        FileImportButton                matlab.ui.control.Button
        FileNameFiled                   matlab.ui.control.EditField
        FromFileButton                  matlab.ui.control.RadioButton
        DropDown                        matlab.ui.control.DropDown
        FromWorkspaceButton             matlab.ui.control.RadioButton
        ImportDataLabel                 matlab.ui.control.Label
        BacktestStrategyTab             matlab.ui.container.Tab
        NextButton_2                    matlab.ui.control.Button
        PreviousButton_2                matlab.ui.control.Button
        DeleteButton                    matlab.ui.control.Button
        DuplicateButton                 matlab.ui.control.Button
        EditButton                      matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        BacktestStrategiesListBox       matlab.ui.control.ListBox
        BacktestStrategiesLabel         matlab.ui.control.Label
        CreateBacktestStrategiesLabel   matlab.ui.control.Label
        BacktestEngineTab               matlab.ui.container.Tab
        NextButton_3                    matlab.ui.control.Button
        PreviousButton_3                matlab.ui.control.Button
        CreateEngineButton              matlab.ui.control.Button
        DefineBacktestEnginePanel       matlab.ui.container.Panel
        PayExpensesFromCashCheckBox     matlab.ui.control.CheckBox
        DateAdjustmentDropDown          matlab.ui.control.DropDown
        DateAdjustmentDropDownLabel     matlab.ui.control.Label
        InitalPortfolioValueEditField   matlab.ui.control.NumericEditField
        InitalPortfolioValueEditFieldLabel  matlab.ui.control.Label
        BasisDropDown                   matlab.ui.control.DropDown
        BasisDropDownLabel              matlab.ui.control.Label
        RatesConventionDropDown         matlab.ui.control.DropDown
        RatesConventionDropDownLabel    matlab.ui.control.Label
        CashBorrowRateEditField         matlab.ui.control.NumericEditField
        CashBorrowRateEditFieldLabel    matlab.ui.control.Label
        RiskFreeRateEditField           matlab.ui.control.NumericEditField
        RiskFreeRateEditFieldLabel      matlab.ui.control.Label
        ChooseBacktestStrategyPanel     matlab.ui.container.Panel
        RefreshButton                   matlab.ui.control.Button
        StrategiesListBox               matlab.ui.control.ListBox
        EngineDataPanel                 matlab.ui.container.Panel
        SignalEditField                 matlab.ui.control.EditField
        SignalDataEditFieldLabel        matlab.ui.control.Label
        PricesDataEditField             matlab.ui.control.EditField
        PricesDataEditFieldLabel        matlab.ui.control.Label
        CreateBacktestEngineLabel       matlab.ui.control.Label
        RunBacktestTab                  matlab.ui.container.Tab
        PreviousButton_4                matlab.ui.control.Button
        ResultsPanel                    matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        EquityCurveTab                  matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        SummaryTable                    matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        StrategyvsMetricButton          matlab.ui.control.StateButton
        AssetAreaCurveTab               matlab.ui.container.Tab
        SelectStrategyDropDown          matlab.ui.control.DropDown
        SelectStrategyDropDownLabel     matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        BacktestRunningParametersPanel  matlab.ui.container.Panel
        RunBacktestButton               matlab.ui.control.Button
        EndDatePicker                   matlab.ui.control.DatePicker
        EndDatePickerLabel              matlab.ui.control.Label
        StartDatePicker                 matlab.ui.control.DatePicker
        StartDatePickerLabel            matlab.ui.control.Label
        DebtAssetsTextArea              matlab.ui.control.TextArea
        DebtAssetsTextAreaLabel         matlab.ui.control.Label
        CashAssetsTextArea              matlab.ui.control.TextArea
        CashAssetsTextAreaLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        CreateStrategyWindow
        strategyWrappers = containers.Map('KeyType', 'single', 'ValueType', 'any');
        maxId = 0;
        backtester
        appSession = struct();
        signalString = '';
        createEngineString = '';
        runBacktestString = '';
    end
    
    properties (Access = public)
        pricesTT
        signalTT
    end
    
    methods (Access = public)        
        function updateStrats(app, stratWrapObj)
            app.strategyWrappers(stratWrapObj.id) = stratWrapObj;
            app.updateStratsListBox();
            app.appSession.StrategyWrappers = app.strategyWrappers;
        end
    end
    
    methods (Access = private)        
        function updateStratsListBox(app)
            ids = keys(app.strategyWrappers);
            resNames = {};
            resIds = {};
            for i = 1:length(ids)
                stratWrapObj = app.strategyWrappers(ids{i});
                resNames = vertcat(resNames, stratWrapObj.name);
                resIds = vertcat(resIds, ids{i});
            end
            app.BacktestStrategiesListBox.Items = resNames;
            app.BacktestStrategiesListBox.ItemsData = resIds;
            app.StrategiesListBox.Items = resNames;
            app.StrategiesListBox.ItemsData = resIds;
            app.SelectStrategyDropDown.Items = resNames;
            app.SelectStrategyDropDown.ItemsData = resNames;
            if isempty(ids)
                app.NextButton_2.Enable = 'off';
                app.NextButton_3.Enable = 'off';
                app.backtester = [];
            else
                app.NextButton_2.Enable = 'on';
            end
        end

        function callCreateStrategy(app, id, stratWrapObj)
            app.DeleteButton.Enable = "off";
            app.EditButton.Enable = "off";
            app.AddButton.Enable = "off";
            app.DuplicateButton.Enable = "off";
            app.NextButton_2.Enable = "off";
            app.PreviousButton_2.Enable = "off";
            app.CreateStrategyWindow = CreateStrategyApp(app, id, stratWrapObj);
            app.DeleteButton.Enable = "on";
            app.EditButton.Enable = "on";
            app.AddButton.Enable = "on";
            app.DuplicateButton.Enable = "on";
            app.NextButton_2.Enable = "on";
            app.PreviousButton_2.Enable = "on";
        end

        function assetAreaPlot(app)
            % Plot the asset allocation as an area plot.
            strategyName = app.SelectStrategyDropDown.Value;
            t = app.backtester.Positions.(strategyName).Time;
            positions = app.backtester.Positions.(strategyName).Variables;
            h = area(app.UIAxes2, t,positions);
            title(app.UIAxes2, sprintf('%s Positions',strrep(strategyName,'_',' ')));
            datetick(app.UIAxes2, 'x','mm/dd','keepticks');
            xlim(app.UIAxes2, [t(1) t(end)])
            oldylim = ylim(app.UIAxes2);
            ylim(app.UIAxes2, [0 oldylim(2)]);
            cm = parula(numel(h));
            for i = 1:numel(h)
                set(h(i),'FaceColor',cm(i,:));
            end
            legend(app.UIAxes2, app.backtester.Positions.(strategyName).Properties.VariableNames)            
        end
        
        function showResults(app)
            if isempty(app.backtester.Positions)
                return;
            end
            % display results
            equityCurve(app.UIAxes,app.backtester);

            a = app.backtester.summary();
            colNames=a.Properties.VariableNames;
            rowNames=a.Properties.RowNames;
            app.UITable.Data = a;
            app.UITable.ColumnName = colNames;
            app.UITable.RowName = rowNames;

            strats = app.StrategiesListBox.Value;
            if isempty(strats) 
                return;
            end
            strategies = {};
            for i = 1:length(strats)
                stratWrapObj = app.strategyWrappers(strats{i});
                strategies = vertcat(strategies,stratWrapObj.name);
            end
            app.SelectStrategyDropDown.Items = strategies;
            app.SelectStrategyDropDown.ItemsData = strategies;
            app.SelectStrategyDropDown.Value = strategies{1};
            app.assetAreaPlot();
        end
        
        function code = generateCodeForStrategies(app, code)
            code = [code
                "% Create Backtest Strategies"];
            if ~isempty(app.strategyWrappers)
                ids = keys(app.strategyWrappers);
                for i = 1:length(ids)
                    stratWrapObj = app.strategyWrappers(ids{i});
                    code  = [code
                        stratWrapObj.codeString
                        newline];
                end
            end
        end
        
        function code = generateCodeForImportingData(app, code)
            selectedButton = app.PricesDataButtonGroup.SelectedObject;
            if isempty(app.pricesTT)
                codeTemplate = ["% No prices data used"
                    "pricesTT = [];"];
            elseif (strcmpi(selectedButton.Text, 'From File'))
                codeTemplate = ["% Import Data Code From File" 
                "importedData= readtable('" + app.FileNameFiled.Value + "');"
                "pricesTT = table2timetable(importedData,'RowTimes','Dates');"];
            else
                codeTemplate = ["% Import Data Code from Workspace"
                    "% Assign prices time table to pricesTT variable"
                    "% pricesTT = " + app.DropDown.Value + ";"];
            end            
            code = [code
                codeTemplate
                newline];
        end

        function code = generateCodeForSignalData(app, code)
            selectedButton = app.SignalDataOptionalButtonGroup.SelectedObject;
            if isempty(app.signalTT)
                codeTemplate = ["% No signal data used"
                    "signalTT = [];"];
            elseif (strcmpi(selectedButton.Text, 'From File'))
                codeTemplate = ["% Import Signal Code From File" 
                "importedSignalData = readtable('" + app.FileNameFiled.Value + "');"
                "signalTT = table2timetable(importedSignalData,'RowTimes','Dates');"];
            else
                codeTemplate = ["% Import Signal Data from Workspace"
                    "% Assign signal time table to signalTT variable"
                    "% signalTT = " + app.DropDown_2.Value + ";"];
            end            
            code = [code
                codeTemplate
                newline];
        end
        
        function generateCodeForCreateEngine(app)
            strategies = "[";
            if ~isempty(app.strategyWrappers)
                ids = keys(app.strategyWrappers);
                for i = 1:length(ids)
                    stratWrapObj = app.strategyWrappers(ids{i});
                    strategies = strategies + stratWrapObj.name + ", ";
                end
                strategies = extractBetween(strategies, 1, strlength(strategies)-2);
            end
            strategies = strategies + "]";
            app.createEngineString = ["% Create BacktestEngine object" 
                "strategies = " + strategies + ";";
                "backtester = backtestEngine(strategies , ..."
               "'RiskFreeRate', " + app.RiskFreeRateEditField.Value + ", ..."
               "'CashBorrowRate', " + app.CashBorrowRateEditField.Value + ", ..."
               "'RatesConvention', '" + app.RatesConventionDropDown.Value + "', ..."
               "'Basis', " + app.BasisDropDown.Value + ", ..."
               "'InitialPortfolioValue', " + app.InitalPortfolioValueEditField.Value + ", ..."
               "'DateAdjustment', '" + app.DateAdjustmentDropDown.Value + "', ..."
               "'PayExpensesFromCash', " + app.PayExpensesFromCashCheckBox.Value + ");"
                newline];
        end
        
        function generateCodeForRunBacktest(app)
            fmt = "dd-MM-yyyy";
            startDate = string(app.StartDatePicker.Value, fmt);
            endDate = string(app.EndDatePicker.Value, fmt);
            app.runBacktestString = ["% Run Backtest"
                "backtester = runBacktest(backtester, pricesTT, ..." 
                "signalTT, 'Start', datetime('" + startDate + "', 'InputFormat', '" + fmt + "'), ..."
                "'End', datetime('" + endDate + "', 'InputFormat', '" + fmt + "'), ..."
                "'CashAssets', " + app.CashAssetsTextArea.Value{1} + ", ..."
                "'DebtAssets', " + app.DebtAssetsTextArea.Value{1} + ");"
                "summary(backtester)"
                "equityCurve(backtester);"
                newline];                
        end
        
        function workspaceVariables = getWorkspaceTimeTables(app)
            workspaceVariables = evalin('base', 'who');
            workspaceData = cellfun(@(x) evalin('base',[x,';']), workspaceVariables, 'UniformOutput', false);
            filterVarFH = @(var) isa(var, 'timetable');
            validVariables = cellfun(filterVarFH, workspaceData); %, workspaceVariables);
            workspaceVariables = [{'Select Variable'}, workspaceVariables{validVariables}];
        end
        
        function setImportedDataTable(app)
            if isempty(app.pricesTT)
                app.DataTable.Data=[];
                app.DataTable.ColumnName=[];
        
                app.NextButton.Enable = 'off';
            else
                importedData = timetable2table(app.pricesTT);
                col_names=importedData.Properties.VariableNames;
                
                %Update the table with imported data         
                app.DataTable.Data=importedData;
                app.DataTable.ColumnName=col_names;
    
                app.StartDatePicker.Value = app.pricesTT.Dates(1);
                app.EndDatePicker.Value = app.pricesTT.Dates(end);
    
                app.NextButton.Enable = 'on';
            end
            app.appSession.pricesTT = app.pricesTT;
        end

        function setImportedSignalTable(app)
            if isempty(app.signalTT)
                app.SignalDataTable.Data=[];
                app.SignalDataTable.ColumnName=[];
            else
                importedData = timetable2table(app.signalTT);
                col_names=importedData.Properties.VariableNames;
                
                %Update the table with imported data         
                app.SignalDataTable.Data=importedData;
                app.SignalDataTable.ColumnName=col_names;
            end
            app.appSession.signalTT = app.signalTT;
        end
        
        function setPricesFromWorkspace(app)
            value = app.DropDown.Value;
            app.FromWorkspaceButton.Value = true;
            if (strcmpi(value, 'Select Variable')) 
                app.pricesTT =  [];
            else
                app.pricesTT = evalin('base', value);
                app.PricesDataEditField.Value = value;
            end
            setImportedDataTable(app);
        end
        
        function setSignalFromWorkspace(app)
            value = app.DropDown_2.Value;
            app.appSession.SignalVar = value;
            app.FromWorkspaceButton_2.Value = true;
            if (strcmpi(value, 'Select Variable')) 
                app.signalTT = [];
            else
                app.signalTT = evalin('base', value);
                app.SignalEditField.Value = value;
            end
            setImportedSignalTable(app);
        end

        function setPricesFromFile(app)
            %Read data from the file
            if isempty(app.FileNameFiled.Value) 
                app.pricesTT = [];
            else
                importedData= readtable(app.FileNameFiled.Value);
                app.pricesTT = table2timetable(importedData,'RowTimes','Dates');
            end
            setImportedDataTable(app);
        end

        function setSignalFromFile(app)
            %Read data from the file
            if isempty(app.SignalTTEditField.Value) 
                app.signalTT = [];
            else
                importedData = readtable(app.SignalTTEditField.Value);
                app.signalTT = table2timetable(importedData,'RowTimes','Dates');
            end            
            setImportedSignalTable(app);
        end
        
        function clearResults(app)
            cla(app.UIAxes, 'reset');
            cla(app.UIAxes2, 'reset');
            app.UITable.Data = [];
            app.runBacktestString = '';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.appSession.signalChoice = 1;
            app.appSession.priceChoice = 1;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.maxId = app.maxId + 1;
            app.callCreateStrategy(app.maxId, []);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            keyToRemove = app.BacktestStrategiesListBox.Value;
            if isKey(app.strategyWrappers, keyToRemove)
                remove(app.strategyWrappers, keyToRemove);
                app.updateStratsListBox();
            end
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            keyToRemove = app.BacktestStrategiesListBox.Value;
            if isKey(app.strategyWrappers, keyToRemove)
                app.callCreateStrategy(keyToRemove, app.strategyWrappers(keyToRemove));
            end
        end

        % Close request function: BacktestingAppUIFigure
        function BacktestingAppUIFigureCloseRequest(app, event)
            delete(app.CreateStrategyWindow);
            delete(app);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.updateStratsListBox();
        end

        % Button pushed function: RunBacktestButton
        function RunBacktestButtonPushed(app, event)
            % Run the backtest
            startDate = app.StartDatePicker.Value;
            endDate = app.EndDatePicker.Value;
            cashAssets = app.CashAssetsTextArea.Value{1};
            debtAssets = app.DebtAssetsTextArea.Value{1};

            % Check for signal data
            app.backtester = runBacktest(app.backtester, app.pricesTT, app.signalTT, ...
                'Start',startDate,...
                'End',endDate, ...
                'CashAssets', eval(cashAssets), ...
                'DebtAssets', eval(debtAssets));

            app.showResults();

            app.appSession.BacktestEngine = app.backtester;
            app.appSession.RunBacktest =struct(...
                'StartDate', app.StartDatePicker.Value, ...
                'EndDate', app.EndDatePicker.Value, ...
                'CashAssets', app.CashAssetsTextArea.Value{1}, ...
                'DebtAssets', app.DebtAssetsTextArea.Value{1});

            app.generateCodeForRunBacktest();
        end

        % Button pushed function: CreateEngineButton
        function CreateEngineButtonPushed(app, event)
            strats = app.StrategiesListBox.Value;
            if isempty(strats) 
                return;
            end
            strategies = [];
            for i = 1:length(strats)
                stratWrapObj = app.strategyWrappers(strats{i});
                strategies = [strategies stratWrapObj.stratObj];
            end
            riskfreerate = app.RiskFreeRateEditField.Value;
            borrowrate = app.CashBorrowRateEditField.Value;
            rateconv = app.RatesConventionDropDown.Value;
            basis = eval(app.BasisDropDown.Value);
            initVal = app.InitalPortfolioValueEditField.Value;
            dateadj = app.DateAdjustmentDropDown.Value;
            app.backtester = backtestEngine(strategies, ...
               'RiskFreeRate', riskfreerate, ...
               'CashBorrowRate', borrowrate, ...
               'RatesConvention', rateconv, ...
               'Basis', basis, ...
               'InitialPortfolioValue', initVal, ...
               'DateAdjustment', dateadj, ...
               'PayExpensesFromCash', app.PayExpensesFromCashCheckBox.Value);
            app.NextButton_3.Enable = 'on';
            app.appSession.BacktestEngine = app.backtester;
            app.appSession.CreateBacktestEngine = struct( ...
                'RiskFreeRate', app.RiskFreeRateEditField.Value, ...
                'BorrowRate', app.CashBorrowRateEditField.Value, ...
                'RateConv', app.RatesConventionDropDown.Value, ...
                'Basis', app.BasisDropDown.Value, ...
                'InitVal', app.InitalPortfolioValueEditField.Value, ...
                'DateAdj', app.DateAdjustmentDropDown.Value, ...
                'Strats', {app.StrategiesListBox.Value});

            app.generateCodeForCreateEngine();
            app.clearResults();
        end

        % Value changed function: SelectStrategyDropDown
        function SelectStrategyDropDownValueChanged(app, event)
            app.assetAreaPlot();
        end

        % Button pushed function: DuplicateButton
        function DuplicateButtonPushed(app, event)
            keyToEdit = app.BacktestStrategiesListBox.Value;
            if length(keyToEdit) > 1
                return 
            end
            if isKey(app.strategyWrappers, keyToEdit)
                app.maxId = app.maxId + 1;
                app.callCreateStrategy(app.maxId, app.strategyWrappers(keyToEdit));
            end
        end

        % Button pushed function: FileImportButton
        function FileImportButtonPushed(app, event)
            %Load the file
            [filename,path] = uigetfile({'*.xlsx', '*.mat'});
            if isempty(filename) 
                return;
            end
            
            %Update the edit filed to show the file name
            app.FromFileButton.Value = true;
            app.FileNameFiled.Value = fullfile(path,filename);
            app.PricesDataEditField.Value = filename;
            app.appSession.Filename = app.FileNameFiled.Value;

            app.setPricesFromFile();
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TabGroup.Children(2);
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.TabGroup.Children(3);
        end

        % Button pushed function: PreviousButton_2
        function PreviousButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.TabGroup.Children(1);
        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.TabGroup.Children(4);
        end

        % Button pushed function: PreviousButton_3
        function PreviousButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.TabGroup.Children(2);
        end

        % Button pushed function: PreviousButton_4
        function PreviousButton_4Pushed(app, event)
            app.TabGroup.SelectedTab = app.TabGroup.Children(3);
        end

        % Button pushed function: ExportSessionButton
        function ExportSessionButtonPushed(app, event)
            app.appSession.maxId = app.maxId;
            assignin('base',"appSession",app.appSession);
        end

        % Button pushed function: ImportSessionButton
        function ImportSessionButtonPushed(app, event)
            [filename,path] = uigetfile('*.mat');
            if ~filename 
                return;
            end
            load(fullfile(path, filename), "appSession");

            app.appSession = appSession; %#ok<ADPROPLC>
            app.maxId = appSession.maxId; %#ok<ADPROPLC>

            if isfield(appSession, 'pricesTT') && ~isempty(appSession.pricesTT) %#ok<ADPROPLC>
                if isfield(appSession, 'Filename') && ~isempty(appSession.Filename) %#ok<ADPROPLC>
                    app.FileNameFiled.Value = appSession.Filename; %#ok<ADPROPLC>
                    app.PricesDataEditField.Value = appSession.Filename; %#ok<ADPROPLC>
                end
                app.pricesTT = appSession.pricesTT; %#ok<ADPROPLC>
                if isfield(appSession, 'PricesVar') && ~isempty(appSession.PricesVar) %#ok<ADPROPLC>
                    workspaceVars = app.getWorkspaceTimeTables();
                    app.DropDown.Items = workspaceVars;
                    app.DropDown.Value = appSession.PricesVar; %#ok<ADPROPLC>
                end
                app.PricesDataButtonGroup.SelectedObject = app.PricesDataButtonGroup.Buttons(appSession.priceChoice); %#ok<ADPROPLC>
                app.setImportedDataTable(); 
            end

            if isfield(appSession, 'StrategyWrappers') && ~isempty(appSession.StrategyWrappers) %#ok<ADPROPLC>
                app.strategyWrappers = appSession.StrategyWrappers; %#ok<ADPROPLC>
                app.updateStratsListBox();
            end

            if isfield(appSession, 'BacktestEngine') && ~isempty(appSession.BacktestEngine) %#ok<ADPROPLC>
                app.backtester = appSession.BacktestEngine; %#ok<ADPROPLC>
                app.NextButton_3.Enable = 'on';
                app.RiskFreeRateEditField.Value = appSession.CreateBacktestEngine.RiskFreeRate; %#ok<ADPROPLC>
                app.CashBorrowRateEditField.Value = appSession.CreateBacktestEngine.BorrowRate; %#ok<ADPROPLC>
                app.RatesConventionDropDown.Value = appSession.CreateBacktestEngine.RateConv; %#ok<ADPROPLC>
                app.BasisDropDown.Value = appSession.CreateBacktestEngine.Basis; %#ok<ADPROPLC>
                app.InitalPortfolioValueEditField.Value = appSession.CreateBacktestEngine.InitVal; %#ok<ADPROPLC>
                app.DateAdjustmentDropDown.Value = appSession.CreateBacktestEngine.DateAdj; %#ok<ADPROPLC>
                app.StrategiesListBox.Value = appSession.CreateBacktestEngine.Strats; %#ok<ADPROPLC>
                app.generateCodeForCreateEngine();
            end

            if isfield(appSession, 'RunBacktest') && ~isempty(appSession.RunBacktest) %#ok<ADPROPLC>
                app.StartDatePicker.Value = appSession.RunBacktest.StartDate; %#ok<ADPROPLC>
                app.EndDatePicker.Value = appSession.RunBacktest.EndDate; %#ok<ADPROPLC>
                app.CashAssetsTextArea.Value{1} = appSession.RunBacktest.CashAssets; %#ok<ADPROPLC>
                app.DebtAssetsTextArea.Value{1} = appSession.RunBacktest.DebtAssets; %#ok<ADPROPLC>
                app.showResults();
                app.generateCodeForRunBacktest();
            end

            if isfield(appSession, 'signalTT') && ~isempty(appSession.signalTT) %#ok<ADPROPLC>
                if isfield(appSession, 'signalFilename') && ~isempty(appSession.signalFilename) %#ok<ADPROPLC>
                    app.SignalTTEditField.Value = appSession.signalFilename; %#ok<ADPROPLC>
                    app.SignalEditField.Value = appSession.signalFilename; %#ok<ADPROPLC>
                end
                app.signalTT = appSession.signalTT; %#ok<ADPROPLC>
                if isfield(appSession, 'SignalVar') && ~isempty(appSession.SignalVar) %#ok<ADPROPLC>
                    workspaceVars = app.getWorkspaceTimeTables();
                    app.DropDown_2.Items = workspaceVars;
                    app.DropDown_2.Value = appSession.SignalVar; %#ok<ADPROPLC>
                    app.SignalEditField.Value = appSession.SignalVar; %#ok<ADPROPLC>
                end
                app.SignalDataOptionalButtonGroup.SelectedObject = app.SignalDataOptionalButtonGroup.Buttons(appSession.signalChoice); %#ok<ADPROPLC>
                app.setImportedSignalTable(); 
            end
        end

        % Button pushed function: ExportToWorkspaceButton
        function ExportToWorkspaceButtonPushed(app, event)
            backtestingStruct.BacktestingStrategies = [];
            if ~isempty(app.strategyWrappers)
                ids = keys(app.strategyWrappers);
                for i = 1:length(ids)
                    stratWrapObj = app.strategyWrappers(ids{i});
                    backtestingStruct.BacktestingStrategies = vertcat(backtestingStruct.BacktestingStrategies, stratWrapObj.stratObj);
                end
            end

            backtestingStruct.BacktestEngine = app.backtester;
            assignin('base',"backtestingStruct",backtestingStruct);
        end

        % Value changed function: StrategyvsMetricButton
        function StrategyvsMetricButtonValueChanged(app, event)
            % value = app.StrategyvsMetricButton.Value;
            X = app.UITable.Data;
            origTable = table2array (X);
            app.UITable.Data = array2table(origTable','RowNames',X.Properties.VariableNames,'VariableNames',X.Properties.RowNames);
            app.UITable.ColumnName=X.Properties.RowNames;
            app.UITable.RowName=X.Properties.VariableNames;
        end

        % Value changed function: SignalTTEditField
        function signalTTEditFieldValueChanged(app, event)
            app.SignalEditField.Value = app.signalTTEditField.Value;
            app.appSession.signalString = app.signalTTEditField.Value;
        end

        % Button pushed function: GenerateCodeButton
        function GenerateCodeButtonPushed(app, event)
            code = [];
            code = app.generateCodeForImportingData(code);
            code = app.generateCodeForSignalData(code);
            code = app.generateCodeForStrategies(code);
            code = [code
                app.createEngineString
                app.runBacktestString];
            code = join(string(code), newline);
            code = strrep(code, "app.BackTestingApp.pricesTT", "pricesTT");
            document = matlab.desktop.editor.newDocument(code);
            document.smartIndentContents();
            uialert(app.BacktestingAppUIFigure, "Code generated in MATLAB Editor!", "Code Generated", "Icon","success");
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
            workspaceVars = app.getWorkspaceTimeTables();
            app.DropDown.Items = workspaceVars;
        end

        % Drop down opening function: DropDown_2
        function DropDown_2Opening(app, event)
            workspaceVars = app.getWorkspaceTimeTables();
            app.DropDown_2.Items = workspaceVars;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.setPricesFromWorkspace();
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            app.setSignalFromWorkspace();
        end

        % Selection changed function: PricesDataButtonGroup
        function PricesDataButtonGroupSelectionChanged(app, event)
            selectedButton = app.PricesDataButtonGroup.SelectedObject;
            if (strcmpi(selectedButton.Text, 'From File'))
                app.setPricesFromFile();
                app.appSession.priceChoice = 1;
            else
                app.appSession.priceChoice = 2;
                app.setPricesFromWorkspace();
            end
        end

        % Button pushed function: FileImportButton_2
        function FileImportButton_2Pushed(app, event)
            %Load the file
            [filename,path] = uigetfile({'*.xlsx', '*.mat'});
            if isempty(filename) 
                return;
            end
            
            %Update the edit filed to show the file name
            app.FromFileButton_2.Value = true;
            app.SignalTTEditField.Value = fullfile(path,filename);
            app.SignalEditField.Value = filename;
            app.appSession.signalFilename = app.SignalTTEditField.Value;

            app.setSignalFromFile();
        end

        % Selection changed function: SignalDataOptionalButtonGroup
        function SignalDataOptionalButtonGroupSelectionChanged(app, event)
            selectedButton = app.SignalDataOptionalButtonGroup.SelectedObject;
            if (strcmpi(selectedButton.Text, 'From File'))
                app.appSession.signalChoice = 1;
                app.setSignalFromFile();
            else
                app.appSession.signalChoice = 2;
                app.setSignalFromWorkspace();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BacktestingAppUIFigure = uifigure('Visible', 'off');
            app.BacktestingAppUIFigure.Name = 'Backtesting App';
            app.BacktestingAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @BacktestingAppUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.BacktestingAppUIFigure);
            app.PricesDataTab = uitab(app.TabGroup);
            app.PricesDataTab.Title = 'Prices Data';
            app.ImportDataLabel = uilabel(app.PricesDataTab);
            app.ImportDataLabel.Text = 'Import Data';
            app.PricesDataButtonGroup = uibuttongroup(app.PricesDataTab);
            app.PricesDataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PricesDataButtonGroupSelectionChanged, true);
            app.PricesDataButtonGroup.Title = 'Prices Data';
            app.FromWorkspaceButton = uiradiobutton(app.PricesDataButtonGroup);
            app.FromWorkspaceButton.Text = 'From Workspace';
            app.FromWorkspaceButton.Value = true;
            app.DropDown = uidropdown(app.PricesDataButtonGroup);
            app.DropDown.Items = {'Select Variable'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Select Variable';
            app.FromFileButton = uiradiobutton(app.PricesDataButtonGroup);
            app.FromFileButton.Text = 'From File';
            app.FileNameFiled = uieditfield(app.PricesDataButtonGroup, 'text');
            app.FileNameFiled.Editable = 'off';
            app.FileImportButton = uibutton(app.PricesDataButtonGroup, 'push');
            app.FileImportButton.ButtonPushedFcn = createCallbackFcn(app, @FileImportButtonPushed, true);
            app.FileImportButton.Text = 'Import';
            app.SignalDataOptionalButtonGroup = uibuttongroup(app.PricesDataTab);
            app.SignalDataOptionalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SignalDataOptionalButtonGroupSelectionChanged, true);
            app.SignalDataOptionalButtonGroup.Title = 'Signal Data (Optional)';
            app.FromWorkspaceButton_2 = uiradiobutton(app.SignalDataOptionalButtonGroup);
            app.FromWorkspaceButton_2.Text = 'From Workspace';
            app.FromWorkspaceButton_2.Value = true;
            app.DropDown_2 = uidropdown(app.SignalDataOptionalButtonGroup);
            app.DropDown_2.Items = {'Select Variable'};
            app.DropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_2Opening, true);
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'Select Variable';
            app.FromFileButton_2 = uiradiobutton(app.SignalDataOptionalButtonGroup);
            app.FromFileButton_2.Text = 'From File';
            app.SignalTTEditField = uieditfield(app.SignalDataOptionalButtonGroup, 'text');
            app.SignalTTEditField.ValueChangedFcn = createCallbackFcn(app, @signalTTEditFieldValueChanged, true);
            app.SignalTTEditField.Editable = 'off';
            app.FileImportButton_2 = uibutton(app.SignalDataOptionalButtonGroup, 'push');
            app.FileImportButton_2.ButtonPushedFcn = createCallbackFcn(app, @FileImportButton_2Pushed, true);
            app.FileImportButton_2.Text = 'Import';
            app.TabGroup3 = uitabgroup(app.PricesDataTab);
            app.PricesDataTab_2 = uitab(app.TabGroup3);
            app.PricesDataTab_2.Title = 'Prices Data';
            app.DataTable = uitable(app.PricesDataTab_2);
            app.DataTable.ColumnName = '';
            app.DataTable.ColumnRearrangeable = 'on';
            app.DataTable.RowName = {};
            app.SignalDataTab = uitab(app.TabGroup3);
            app.SignalDataTab.Title = 'Signal Data';
            app.SignalDataTable = uitable(app.SignalDataTab);
            app.SignalDataTable.ColumnName = '';
            app.SignalDataTable.ColumnRearrangeable = 'on';
            app.SignalDataTable.RowName = {};
            app.NextButton = uibutton(app.PricesDataTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.BacktestStrategyTab = uitab(app.TabGroup);
            app.BacktestStrategyTab.Title = 'Backtest Strategy';
            app.CreateBacktestStrategiesLabel = uilabel(app.BacktestStrategyTab);
            app.CreateBacktestStrategiesLabel.Text = 'Create Backtest Strategies';
            app.BacktestStrategiesLabel = uilabel(app.BacktestStrategyTab);
            app.BacktestStrategiesLabel.Text = {'Backtest'; 'Strategies'};
            app.BacktestStrategiesListBox = uilistbox(app.BacktestStrategyTab);
            app.BacktestStrategiesListBox.Items = {};
            app.BacktestStrategiesListBox.Value = {};
            app.AddButton = uibutton(app.BacktestStrategyTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.EditButton = uibutton(app.BacktestStrategyTab, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.DuplicateButton = uibutton(app.BacktestStrategyTab, 'push');
            app.DuplicateButton.ButtonPushedFcn = createCallbackFcn(app, @DuplicateButtonPushed, true);
            app.DuplicateButton.Text = 'Duplicate';
            app.DeleteButton = uibutton(app.BacktestStrategyTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.PreviousButton_2 = uibutton(app.BacktestStrategyTab, 'push');
            app.PreviousButton_2.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_2Pushed, true);
            app.PreviousButton_2.Text = 'Previous';
            app.NextButton_2 = uibutton(app.BacktestStrategyTab, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Enable = 'off';
            app.NextButton_2.Text = 'Next';
            app.BacktestEngineTab = uitab(app.TabGroup);
            app.BacktestEngineTab.Title = 'Backtest Engine';
            app.CreateBacktestEngineLabel = uilabel(app.BacktestEngineTab);
            app.CreateBacktestEngineLabel.Text = 'Create Backtest Engine';
            app.EngineDataPanel = uipanel(app.BacktestEngineTab);
            app.EngineDataPanel.Title = 'Engine Data';
            app.PricesDataEditFieldLabel = uilabel(app.EngineDataPanel);
            app.PricesDataEditFieldLabel.Text = 'Prices Data';
            app.PricesDataEditField = uieditfield(app.EngineDataPanel, 'text');
            app.PricesDataEditField.Editable = 'off';
            app.SignalDataEditFieldLabel = uilabel(app.EngineDataPanel);
            app.SignalDataEditFieldLabel.Text = 'Signal Data';
            app.SignalEditField = uieditfield(app.EngineDataPanel, 'text');
            app.SignalEditField.Editable = 'off';
            app.DefineBacktestEnginePanel = uipanel(app.BacktestEngineTab);
            app.DefineBacktestEnginePanel.Title = 'Define Backtest Engine';
            app.ChooseBacktestStrategyPanel = uipanel(app.DefineBacktestEnginePanel);
            app.ChooseBacktestStrategyPanel.Title = 'Choose Backtest Strategy';
            app.StrategiesListBox = uilistbox(app.ChooseBacktestStrategyPanel);
            app.StrategiesListBox.Items = {};
            app.StrategiesListBox.Multiselect = 'on';
            app.StrategiesListBox.Value = {};
            app.RefreshButton = uibutton(app.ChooseBacktestStrategyPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.RiskFreeRateEditFieldLabel = uilabel(app.DefineBacktestEnginePanel);
            app.RiskFreeRateEditFieldLabel.Text = 'Risk Free Rate';
            app.RiskFreeRateEditField = uieditfield(app.DefineBacktestEnginePanel, 'numeric');
            app.CashBorrowRateEditFieldLabel = uilabel(app.DefineBacktestEnginePanel);
            app.CashBorrowRateEditFieldLabel.Text = 'Cash Borrow Rate';
            app.CashBorrowRateEditField = uieditfield(app.DefineBacktestEnginePanel, 'numeric');
            app.RatesConventionDropDownLabel = uilabel(app.DefineBacktestEnginePanel);
            app.RatesConventionDropDownLabel.Text = 'Rates Convention';
            app.RatesConventionDropDown = uidropdown(app.DefineBacktestEnginePanel);
            app.RatesConventionDropDown.Items = {'Annualized', 'PerStep'};
            app.RatesConventionDropDown.Value = 'Annualized';
            app.BasisDropDownLabel = uilabel(app.DefineBacktestEnginePanel);
            app.BasisDropDownLabel.Text = 'Basis';
            app.BasisDropDown = uidropdown(app.DefineBacktestEnginePanel);
            app.BasisDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.BasisDropDown.Value = '0';
            app.InitalPortfolioValueEditFieldLabel = uilabel(app.DefineBacktestEnginePanel);
            app.InitalPortfolioValueEditFieldLabel.Text = 'Inital Portfolio Value';
            app.InitalPortfolioValueEditField = uieditfield(app.DefineBacktestEnginePanel, 'numeric');
            app.InitalPortfolioValueEditField.Value = 10000;
            app.DateAdjustmentDropDownLabel = uilabel(app.DefineBacktestEnginePanel);
            app.DateAdjustmentDropDownLabel.Text = 'Date Adjustment';
            app.DateAdjustmentDropDown = uidropdown(app.DefineBacktestEnginePanel);
            app.DateAdjustmentDropDown.Items = {'Previous', 'Next', 'None'};
            app.DateAdjustmentDropDown.Value = 'Previous';
            app.PayExpensesFromCashCheckBox = uicheckbox(app.DefineBacktestEnginePanel);
            app.PayExpensesFromCashCheckBox.Text = 'Pay Expenses From Cash ';
            app.CreateEngineButton = uibutton(app.BacktestEngineTab, 'push');
            app.CreateEngineButton.ButtonPushedFcn = createCallbackFcn(app, @CreateEngineButtonPushed, true);
            app.CreateEngineButton.Text = 'Create Engine';
            app.PreviousButton_3 = uibutton(app.BacktestEngineTab, 'push');
            app.PreviousButton_3.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_3Pushed, true);
            app.PreviousButton_3.Text = 'Previous';
            app.NextButton_3 = uibutton(app.BacktestEngineTab, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed, true);
            app.NextButton_3.Enable = 'off';
            app.NextButton_3.Text = 'Next';
            app.RunBacktestTab = uitab(app.TabGroup);
            app.RunBacktestTab.Title = 'Run Backtest';
            app.BacktestRunningParametersPanel = uipanel(app.RunBacktestTab);
            app.BacktestRunningParametersPanel.Title = 'Backtest Running Parameters';
            app.CashAssetsTextAreaLabel = uilabel(app.BacktestRunningParametersPanel);
            app.CashAssetsTextAreaLabel.Text = 'Cash Assets';
            app.CashAssetsTextArea = uitextarea(app.BacktestRunningParametersPanel);
            app.CashAssetsTextArea.Value = {'[]'};
            app.DebtAssetsTextAreaLabel = uilabel(app.BacktestRunningParametersPanel);
            app.DebtAssetsTextAreaLabel.Text = 'Debt Assets';
            app.DebtAssetsTextArea = uitextarea(app.BacktestRunningParametersPanel);
            app.DebtAssetsTextArea.Value = {'[]'};
            app.StartDatePickerLabel = uilabel(app.BacktestRunningParametersPanel);
            app.StartDatePickerLabel.Text = 'Start';
            app.StartDatePicker = uidatepicker(app.BacktestRunningParametersPanel);
            app.EndDatePickerLabel = uilabel(app.BacktestRunningParametersPanel);
            app.EndDatePickerLabel.Text = 'End';
            app.EndDatePicker = uidatepicker(app.BacktestRunningParametersPanel);
            app.RunBacktestButton = uibutton(app.BacktestRunningParametersPanel, 'push');
            app.RunBacktestButton.ButtonPushedFcn = createCallbackFcn(app, @RunBacktestButtonPushed, true);
            app.RunBacktestButton.Text = 'Run Backtest';
            app.ResultsPanel = uipanel(app.RunBacktestTab);
            app.ResultsPanel.Title = 'Results';
            app.TabGroup2 = uitabgroup(app.ResultsPanel);
            app.EquityCurveTab = uitab(app.TabGroup2);
            app.EquityCurveTab.Title = 'Equity Curve';
            app.UIAxes = uiaxes(app.EquityCurveTab);
            title(app.UIAxes, 'Equity Curve')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Portfolio Value')
            zlabel(app.UIAxes, 'Z')
            app.SummaryTable = uitab(app.TabGroup2);
            app.SummaryTable.Title = 'Summary Table';
            app.StrategyvsMetricButton = uibutton(app.SummaryTable, 'state');
            app.StrategyvsMetricButton.ValueChangedFcn = createCallbackFcn(app, @StrategyvsMetricButtonValueChanged, true);
            app.StrategyvsMetricButton.Text = 'Strategy vs Metric';
            app.UITable = uitable(app.SummaryTable);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable.ColumnRearrangeable = 'on';
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.AssetAreaCurveTab = uitab(app.TabGroup2);
            app.AssetAreaCurveTab.Title = 'Asset Area Curve';
            app.UIAxes2 = uiaxes(app.AssetAreaCurveTab);
            title(app.UIAxes2, 'Positions')
            xlabel(app.UIAxes2, 'Date')
            ylabel(app.UIAxes2, 'Asset Positions')
            zlabel(app.UIAxes2, 'Z')
            app.SelectStrategyDropDownLabel = uilabel(app.AssetAreaCurveTab);
            app.SelectStrategyDropDownLabel.Text = 'Select Strategy';
            app.SelectStrategyDropDown = uidropdown(app.AssetAreaCurveTab);
            app.SelectStrategyDropDown.Items = {};
            app.SelectStrategyDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectStrategyDropDownValueChanged, true);
            app.SelectStrategyDropDown.Value = {};
            app.PreviousButton_4 = uibutton(app.RunBacktestTab, 'push');
            app.PreviousButton_4.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_4Pushed, true);
            app.PreviousButton_4.Text = 'Previous';
            app.ExportSessionButton = uibutton(app.BacktestingAppUIFigure, 'push');
            app.ExportSessionButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSessionButtonPushed, true);
            app.ExportSessionButton.Text = 'Export Session';
            app.ImportSessionButton = uibutton(app.BacktestingAppUIFigure, 'push');
            app.ImportSessionButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSessionButtonPushed, true);
            app.ImportSessionButton.Text = 'Import Session';
            app.ExportToWorkspaceButton = uibutton(app.BacktestingAppUIFigure, 'push');
            app.ExportToWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportToWorkspaceButtonPushed, true);
            app.ExportToWorkspaceButton.Text = 'Export To Workspace';
            app.GenerateCodeButton = uibutton(app.BacktestingAppUIFigure, 'push');
            app.GenerateCodeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCodeButtonPushed, true);
            app.GenerateCodeButton.Text = 'Generate Code';
            % Show the figure after all components are created
            app.BacktestingAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BacktestingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BacktestingAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BacktestingAppUIFigure)
        end
    end
end