classdef CreateStrategyApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CreateBacktestStrategyUIFigure  matlab.ui.Figure
        ConfigureBacktestStrategyPanel  matlab.ui.container.Panel
        CreateButton                    matlab.ui.control.Button
        PerformanceHurdleEditField      matlab.ui.control.EditField
        PerformanceHurdleEditFieldLabel  matlab.ui.control.Label
        PerformanceFeeEditField         matlab.ui.control.EditField
        PerformanceFeeEditFieldLabel    matlab.ui.control.Label
        ManagementFeeEditField          matlab.ui.control.EditField
        ManagementFeeEditFieldLabel     matlab.ui.control.Label
        InitialWeightsButtonGroup       matlab.ui.container.ButtonGroup
        CustomVector                    matlab.ui.control.EditField
        CustomVectorButton              matlab.ui.control.RadioButton
        WarmupdaysEditField             matlab.ui.control.EditField
        WarmupdaysLabel                 matlab.ui.control.Label
        RebalanceFcnButton              matlab.ui.control.RadioButton
        EqualButton                     matlab.ui.control.RadioButton
        ZeroButton                      matlab.ui.control.RadioButton
        UserDataEditField               matlab.ui.control.EditField
        UserDataLabel                   matlab.ui.control.Label
        EngineDataListListBox           matlab.ui.control.ListBox
        EngineDataListListBoxLabel      matlab.ui.control.Label
        LookbackWindowDaysPanel         matlab.ui.container.Panel
        ToEditField_2                   matlab.ui.control.EditField
        ToEditField_2Label              matlab.ui.control.Label
        FromEditField                   matlab.ui.control.EditField
        FromEditFieldLabel              matlab.ui.control.Label
        TransactionCostsEditField       matlab.ui.control.EditField
        TransactionCostsEditFieldLabel  matlab.ui.control.Label
        RebalanceFrequencyDaysEditField  matlab.ui.control.EditField
        RebalanceFrequencyDaysEditFieldLabel  matlab.ui.control.Label
        RebalanceFcnHandleEditField     matlab.ui.control.EditField
        RebalanceFcnHandleEditFieldLabel  matlab.ui.control.Label
        StrategyNameEditField           matlab.ui.control.EditField
        StrategyNameEditFieldLabel      matlab.ui.control.Label
        PreexecCustomCodeOptionalPanel  matlab.ui.container.Panel
        PreExecuteCodeOptionalTextArea  matlab.ui.control.TextArea
        CreateBacktestStrategyLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        BackTestingApp % Calling app
        Id
    end
    
    methods (Access = public)
        
        function values = createValuesStruct(app)
            values.name = app.StrategyNameEditField.Value;
            values.fcnHandle = app.RebalanceFcnHandleEditField.Value;
            values.rebalanceFrequency = app.RebalanceFrequencyDaysEditField.Value;
            values.transactionCosts = app.TransactionCostsEditField.Value;
            values.from = app.FromEditField.Value;
            values.to = app.ToEditField_2.Value;

            % values.initialWeights = app.InitialWeightsButtonGroup.SelectedObject.Value;
            values.initWarmup = app.WarmupdaysEditField.Value;
            values.initVector = app.CustomVector.Value;
            values.userData = app.UserDataEditField.Value;
            values.engineDataList = app.EngineDataListListBox.Value;
            values.customCode = app.PreExecuteCodeOptionalTextArea.Value;
            values.managementFee = app.ManagementFeeEditField.Value;
            values.id = app.Id;
            values.performanceHurdle = app.PerformanceHurdleEditField.Value;
            values.performanceFee = app.PerformanceFeeEditField.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, id, values)
            app.BackTestingApp = caller;
            app.Id = id;
            if ~isempty(values)
                app.PreExecuteCodeOptionalTextArea.Value = values.customCode;
                app.StrategyNameEditField.Value = values.name;
                app.RebalanceFcnHandleEditField.Value = values.fcnHandle;
                app.RebalanceFrequencyDaysEditField.Value = values.rebalanceFrequency;
                app.TransactionCostsEditField.Value = values.transactionCosts;
                app.FromEditField.Value = values.from;
                app.ToEditField_2.Value = values.to;
                app.InitialWeightsButtonGroup.SelectedObject = app.InitialWeightsButtonGroup.Buttons(values.initialWeights);
                app.WarmupdaysEditField.Value = values.initWarmup;
                app.CustomVector.Value = values.initVector;
                app.UserDataEditField.Value = values.userData;
                app.EngineDataListListBox.Value = values.engineDataList;
                app.ManagementFeeEditField.Value = values.managementFee;
                app.PerformanceHurdleEditField.Value = values.performanceHurdle;
                app.PerformanceFeeEditField.Value = values.performanceFee;
            end
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            for i = 1:length(app.PreExecuteCodeOptionalTextArea.Value)
                eval(app.PreExecuteCodeOptionalTextArea.Value{i});
            end
            values = createValuesStruct(app);
            functionHandle = eval(values.fcnHandle);
            rebalanceFrequency = eval(values.rebalanceFrequency);
            transactionCosts = eval(values.transactionCosts);
            lookbackWindow = eval("[" + values.from + ", " + values.to + "]");
            managementFee = eval(values.managementFee);
            performanceFee = eval(values.performanceFee);
            performanceHurdle = eval(values.performanceHurdle);

            numAssets = size(app.BackTestingApp.pricesTT.Variables, 2);
            initValuesStr = '';
            if app.ZeroButton.Value
                initialWeights = zeros(1, numAssets);
                initValuesStr = 'zeros(1, numAssets);';
                values.initialWeights = 1;
            elseif app.EqualButton.Value
                initialWeights = ones(1, numAssets);
                initialWeights = initialWeights / sum(initialWeights);
                initValuesStr = "ones(1, numAssets); initialWeights = initialWeights / sum(initialWeights);";
                values.initialWeights = 2;
            elseif app.RebalanceFcnButton.Value
                initialWeights = zeros(1, numAssets);
                warmpupDays = eval(values.initWarmup);
                pricesTT = app.BackTestingApp.pricesTT;
                startDate = pricesTT.Dates(1);
                warmupTT = pricesTT(startDate:startDate + warmpupDays,:);
                initialWeights = functionHandle(initialWeights, warmupTT);
                initValuesStr = "ones(1, numAssets); warmupTT = pricesTT(1: " + string(warmpupDays+1) + ",:); initialWeights = " + extractBetween(values.fcnHandle, 2, strlength(values.fcnHandle)) + "(initialWeights, warmupTT);";
                values.initialWeights = 3;
            elseif app.CustomVectorButton.Value
                initialWeights = eval(values.initVector);
                initValuesStr = values.initVector + ";";
                values.initialWeights = 4;
            end
            
            userData = eval(values.userData);
            engineDataList = string.empty();
            engineDataString = "string.empty()";
            if ~isempty(values.engineDataList{1})
                engineDataList = string(values.engineDataList);
                engineDataString = engineDataList;
            end

            values.stratObj = backtestStrategy(values.name, functionHandle, ...
                "RebalanceFrequency", rebalanceFrequency, ...
                "TransactionCosts", transactionCosts, ...
                "LookbackWindow", lookbackWindow, ...
                "InitialWeights", initialWeights, ...
                "UserData", userData, ...
                "EngineDataList", engineDataList, ...
                "ManagementFee", managementFee, ...
                "PerformanceFee", performanceFee, ...
                "PerformanceHurdle", performanceHurdle);

            codeTemplate = [string(app.PreExecuteCodeOptionalTextArea.Value)
                "numAssets = " + numAssets + ";"
                "strategyName = '" + values.name + "'; rebalanceFunction = " + values.fcnHandle + ";"
                "rebalanceFrequency = " + values.rebalanceFrequency + "; transactionCosts = " + values.transactionCosts + ";"
                "lookbackWindow = [" + values.from + " " + values.to + "]; initialWeights = " + string(initValuesStr) + " "
                "userData = " + string(values.userData) + ";"
                "engineDataList = " + engineDataString + "; managementFee = " + values.managementFee + ";"
                "performanceFee= " + values.performanceFee + "; performanceHurdle = " + values.performanceHurdle + ";"
                values.name + " = backtestStrategy(strategyName, rebalanceFunction, ..."
                "    ""RebalanceFrequency"", rebalanceFrequency, ..."
                "    ""TransactionCosts"", transactionCosts, ..."
                "    ""InitialWeights"",initialWeights, ..."
                "    ""LookbackWindow"",lookbackWindow, ..."
                "    ""UserData"", userData, ..."
                "    ""EngineDataList"", engineDataList, ..."
                "    ""ManagementFee"", managementFee, ..."
                "    ""PerformanceFee"", performanceFee, ..."
                "    ""PerformanceHurdle"", performanceHurdle);"
                "clear strategyName rebalanceFunction rebalanceFrequency;"
                "clear transactionCosts lookbackWindow initialWeights;"
                "clear userData engineDataList;"];
            code = join(string(codeTemplate), newline);
            values.codeString = code;

            updateStrats(app.BackTestingApp, values);
            delete(app);
        end

        % Close request function: CreateBacktestStrategyUIFigure
        function CreateBacktestStrategyUIFigureCloseRequest(app, event)
            app.BackTestingApp.DeleteButton.Enable = "on";
            app.BackTestingApp.EditButton.Enable = "on";
            app.BackTestingApp.AddButton.Enable = "on";
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CreateBacktestStrategyUIFigure = uifigure('Visible', 'off');
            app.CreateBacktestStrategyUIFigure.Name = 'Create Backtest Strategy';
            app.CreateBacktestStrategyUIFigure.CloseRequestFcn = createCallbackFcn(app, @CreateBacktestStrategyUIFigureCloseRequest, true);
            app.CreateBacktestStrategyLabel = uilabel(app.CreateBacktestStrategyUIFigure);
            app.CreateBacktestStrategyLabel.Text = 'Create Backtest Strategy';
            app.PreexecCustomCodeOptionalPanel = uipanel(app.CreateBacktestStrategyUIFigure);
            app.PreexecCustomCodeOptionalPanel.Title = 'Pre-exec Custom Code (Optional)';
            app.PreExecuteCodeOptionalTextArea = uitextarea(app.PreexecCustomCodeOptionalPanel);
            app.PreExecuteCodeOptionalTextArea.Value = {'% Add code to calculate variables to be used in the form below. '};
            app.ConfigureBacktestStrategyPanel = uipanel(app.CreateBacktestStrategyUIFigure);
            app.ConfigureBacktestStrategyPanel.Title = 'Configure Backtest Strategy';
            app.StrategyNameEditFieldLabel = uilabel(app.ConfigureBacktestStrategyPanel);
            app.StrategyNameEditFieldLabel.Text = 'Strategy Name';
            app.StrategyNameEditField = uieditfield(app.ConfigureBacktestStrategyPanel, 'text');
            app.StrategyNameEditField.Value = 'DefaultStrategy';
            app.RebalanceFcnHandleEditFieldLabel = uilabel(app.ConfigureBacktestStrategyPanel);
            app.RebalanceFcnHandleEditFieldLabel.Text = 'Rebalance Fcn Handle';
            app.RebalanceFcnHandleEditField = uieditfield(app.ConfigureBacktestStrategyPanel, 'text');
            app.RebalanceFcnHandleEditField.Value = '@(w,~,~) w';
            app.RebalanceFrequencyDaysEditFieldLabel = uilabel(app.ConfigureBacktestStrategyPanel);
            app.RebalanceFrequencyDaysEditFieldLabel.Text = 'Rebalance Frequency (Days)';
            app.RebalanceFrequencyDaysEditField = uieditfield(app.ConfigureBacktestStrategyPanel, 'text');
            app.RebalanceFrequencyDaysEditField.Value = '1';
            app.TransactionCostsEditFieldLabel = uilabel(app.ConfigureBacktestStrategyPanel);
            app.TransactionCostsEditFieldLabel.Text = 'Transaction Costs';
            app.TransactionCostsEditField = uieditfield(app.ConfigureBacktestStrategyPanel, 'text');
            app.TransactionCostsEditField.Value = '0';
            app.LookbackWindowDaysPanel = uipanel(app.ConfigureBacktestStrategyPanel);
            app.LookbackWindowDaysPanel.Title = 'Lookback Window (Days)';
            app.FromEditFieldLabel = uilabel(app.LookbackWindowDaysPanel);
            app.FromEditFieldLabel.Text = 'From';
            app.FromEditField = uieditfield(app.LookbackWindowDaysPanel, 'text');
            app.FromEditField.Value = '0';
            app.ToEditField_2Label = uilabel(app.LookbackWindowDaysPanel);
            app.ToEditField_2Label.Text = 'To';
            app.ToEditField_2 = uieditfield(app.LookbackWindowDaysPanel, 'text');
            app.ToEditField_2.Value = 'Inf';
            app.EngineDataListListBoxLabel = uilabel(app.ConfigureBacktestStrategyPanel);
            app.EngineDataListListBoxLabel.Text = {'Engine'; 'Data'; 'List'};
            app.EngineDataListListBox = uilistbox(app.ConfigureBacktestStrategyPanel);
            app.EngineDataListListBox.Items = {'None', 'Weights', 'WeightsHistory', 'PortfolioValue', 'PortfolioValueHistory', 'FeesHistory', 'TransactionCostHistory', 'CashAsset', 'DebtAsset'};
            app.EngineDataListListBox.ItemsData = {'', 'Weights', 'WeightsHistory', 'PortfolioValue', 'PortfolioValueHistory', 'FeesHistory', 'TransactionCostHistory', 'CashAsset', 'DebtAsset'};
            app.EngineDataListListBox.Multiselect = 'on';
            app.EngineDataListListBox.Value = {''};
            app.UserDataLabel = uilabel(app.ConfigureBacktestStrategyPanel);
            app.UserDataLabel.Text = 'User Data';
            app.UserDataEditField = uieditfield(app.ConfigureBacktestStrategyPanel, 'text');
            app.UserDataEditField.Value = '[]';
            app.InitialWeightsButtonGroup = uibuttongroup(app.ConfigureBacktestStrategyPanel);
            app.InitialWeightsButtonGroup.Title = 'Initial Weights';
            app.ZeroButton = uiradiobutton(app.InitialWeightsButtonGroup);
            app.ZeroButton.Text = 'Zero';
            app.ZeroButton.Value = true;
            app.EqualButton = uiradiobutton(app.InitialWeightsButtonGroup);
            app.EqualButton.Text = 'Equal';
            app.RebalanceFcnButton = uiradiobutton(app.InitialWeightsButtonGroup);
            app.RebalanceFcnButton.Text = 'Rebalance Fcn';
            app.WarmupdaysLabel = uilabel(app.InitialWeightsButtonGroup);
            app.WarmupdaysLabel.Text = 'Warmup(days)';
            app.WarmupdaysEditField = uieditfield(app.InitialWeightsButtonGroup, 'text');
            app.WarmupdaysEditField.Value = '0';
            app.CustomVectorButton = uiradiobutton(app.InitialWeightsButtonGroup);
            app.CustomVectorButton.Text = 'Custom Vector';
            app.CustomVector = uieditfield(app.InitialWeightsButtonGroup, 'text');
            app.CustomVector.Value = '[]';
            app.ManagementFeeEditFieldLabel = uilabel(app.ConfigureBacktestStrategyPanel);
            app.ManagementFeeEditFieldLabel.Text = 'Management Fee';
            app.ManagementFeeEditField = uieditfield(app.ConfigureBacktestStrategyPanel, 'text');
            app.ManagementFeeEditField.Value = '0';
            app.PerformanceFeeEditFieldLabel = uilabel(app.ConfigureBacktestStrategyPanel);
            app.PerformanceFeeEditFieldLabel.Text = 'Performance Fee';
            app.PerformanceFeeEditField = uieditfield(app.ConfigureBacktestStrategyPanel, 'text');
            app.PerformanceFeeEditField.Value = '0';
            app.PerformanceHurdleEditFieldLabel = uilabel(app.ConfigureBacktestStrategyPanel);
            app.PerformanceHurdleEditFieldLabel.Text = 'Performance Hurdle';
            app.PerformanceHurdleEditField = uieditfield(app.ConfigureBacktestStrategyPanel, 'text');
            app.PerformanceHurdleEditField.Value = '0';
            app.CreateButton = uibutton(app.ConfigureBacktestStrategyPanel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            % Show the figure after all components are created
            app.CreateBacktestStrategyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CreateStrategyApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CreateBacktestStrategyUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CreateBacktestStrategyUIFigure)
        end
    end
end